classdef one_cycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnecycleUIFigure              matlab.ui.Figure
        SegmentationAVCheckBox        matlab.ui.control.CheckBox
        ReferenceDirectory            matlab.ui.control.TextArea
        FilesDirectory                matlab.ui.control.TextArea
        ErrorLabel                    matlab.ui.control.Label
        OutputLength                  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        ApplytoreferencetooCheckBox   matlab.ui.control.CheckBox
        ClearButton                   matlab.ui.control.Button
        LoadreferenceButton           matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        NumberofframesEditField       matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel  matlab.ui.control.Label
        LoadfilesButton               matlab.ui.control.Button
    end

    properties (Access = private)
        files = {}
        reference
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            [file,path] = uigetfile(strcat(app.reference.directory,'*.avi;*.raw'),'Select files','MultiSelect','on');
            if ~isfloat(file)
                if ~iscell(file)
                    file  = {file};
                end
                if length(file)~= app.reference.nbFiles
                    app.ErrorLabel.Text = "Error: Number of files not correct" ; 
                    return ; 
                end
                app.LoadfilesButton.Enable = false ; 
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                app.files{end + 1} = OneCycleClass(file,path);
                app.OutputLength.Text = num2str(length(app.files)) ;
                if strcmp(app.FilesDirectory.Value, '')
                    app.FilesDirectory.Value = path ;
                else
                    app.FilesDirectory.Value{end + 1} = path;
                end
            end
            app.ErrorLabel.Text = "" ; 
            app.LoadfilesButton.Enable = true ;
            app.Lamp.Color = [0, 1, 0] ;
        end

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            if (isempty(app.reference))
                [file,path] = uigetfile('*.avi;*.raw','Select reference files','MultiSelect','on');
            else
                [file,path] = uigetfile(strcat(app.reference.directory, '*.avi;*.raw'),'Select reference files','MultiSelect','on');
            end
            if ~isfloat(file)
                for n = 1:length(app.files)
                    if length(file)~=app.files{n}.nbFiles 
                        app.ErrorLabel.Text = "Error: number of files not correct";
                        return ; 
                    end 
                end
                app.Lamp.Color = [1, 0, 0];
                drawnow;
                if ~iscell(file)
                    file = {file};
                end
                app.reference = OneCycleClass(file,path);
                app.LoadfilesButton.Enable = true ;
                app.ExecuteButton.Enable = true ;
                app.ClearButton.Enable = true ;
                app.ReferenceDirectory.Value = path ; 
            end
            app.ErrorLabel.Text = "" ;
            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.Lamp.Color = [1, 0, 0];
            app.ErrorLabel.Text = "" ;
            drawnow;
            sys_index_list_cell = app.reference.getSystole();
            if app.ApplytoreferencetooCheckBox.Value 
%                 disp(app.reference.filenames) ;
%                 [~,name,~] = fileparts(app.reference.filenames) ;
%                 disp(fullfile('./',strcat(name,'.mat'))) ; 
%                 cache_exists = exist(fullfile('./',strcat(name,'.mat'))) ;
%                 disp(cache_exists) ; 
                app.reference.writeCut(sys_index_list_cell,app.NumberofframesEditField.Value, app.SegmentationAVCheckBox.Value) ; 
%                 current_directory = pwd ; 
%                 cd(app.reference.directory) ; 
%                 cd .. ;
%                 cache_exists = exist(fullfile('./',strcat(name,'.mat'))) ; 
%                 if cache_exists
%                     load(fullfile('./',strcat(name,'.mat'))); 
% 
% 
%                     cd(fullfile(app.reference.directory,'one_cycle_2'); 
% % dans le répertoire one_cycle_idx et ajouter _data.txt avec le cache
% 
%                     fileID = fopen('plop.txt','w');
%                     fwrite(fileID,(cache.batch_stide,cache.Fs,'integer*4');
%                     fclose(fileID);
% 
%                 end
%                 
%                 if cache_exists
%                     fileID = fopen('magic5.bin','w');
%                     fwrite(fileID,magic(5),'integer*4');
%                     fclose(fileID);
%                 end 
%                 cd(current_directory) ;
            end
            for n = 1:length(app.files)
                app.files{n}.writeCut(sys_index_list_cell, app.NumberofframesEditField.Value, app.SegmentationAVCheckBox.Value);
            end


            app.Lamp.Color = [0, 1, 0];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.files = {};
            app.OutputLength.Text = num2str(0) ;
            app.FilesDirectory.Value = "";
            app.LoadfilesButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnecycleUIFigure = uifigure('Visible', 'off');
            app.OnecycleUIFigure.Name = 'One cycle';
            app.LoadfilesButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Enable = 'off';
            app.LoadfilesButton.Text = 'Load files';
            app.NumberofframesEditFieldLabel = uilabel(app.OnecycleUIFigure);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames ';
            app.NumberofframesEditField = uieditfield(app.OnecycleUIFigure, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 64;
            app.ExecuteButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.LoadreferenceButton = uibutton(app.OnecycleUIFigure, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = 'Load reference';
            app.ClearButton = uibutton(app.OnecycleUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ApplytoreferencetooCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.ApplytoreferencetooCheckBox.Text = 'Apply to reference too';
            app.Lamp = uilamp(app.OnecycleUIFigure);
            app.OutputLength = uilabel(app.OnecycleUIFigure);
            app.OutputLength.Text = '0';
            app.ErrorLabel = uilabel(app.OnecycleUIFigure);
            app.ErrorLabel.Text = '';
            app.FilesDirectory = uitextarea(app.OnecycleUIFigure);
            app.FilesDirectory.Editable = 'off';
            app.ReferenceDirectory = uitextarea(app.OnecycleUIFigure);
            app.SegmentationAVCheckBox = uicheckbox(app.OnecycleUIFigure);
            app.SegmentationAVCheckBox.Text = 'SegmentationAV';
            % Show the figure after all components are created
            app.OnecycleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = one_cycle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnecycleUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnecycleUIFigure)
        end
    end
end